classdef LIT_FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        previewnumSpinner               matlab.ui.control.Spinner
        previewnumSpinnerLabel          matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        debugButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        EnpLabTextArea                  matlab.ui.control.TextArea
        EnpLabTextAreaLabel             matlab.ui.control.Label
        complianceSpinner               matlab.ui.control.Spinner
        complianceSpinnerLabel          matlab.ui.control.Label
        pulsedbiasparamterSCPILabel     matlab.ui.control.Label
        pulsedbiasSpinner               matlab.ui.control.Spinner
        pulsedbiasSpinnerLabel          matlab.ui.control.Label
        DisconnectButton                matlab.ui.control.Button
        heatingtimeSpinner              matlab.ui.control.Spinner
        heatingtimeSpinnerLabel         matlab.ui.control.Label
        SettingSaveButton               matlab.ui.control.Button
        LockinThermographyusingFFTLabel  matlab.ui.control.Label
        imageprocessingparameterLabel   matlab.ui.control.Label
        framecountSpinner               matlab.ui.control.Spinner
        framecountSpinnerLabel          matlab.ui.control.Label
        f_lockinSpinner                 matlab.ui.control.Spinner
        f_lockinSpinnerLabel            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        FFTSNRPanel                     matlab.ui.container.Panel
        startingframeEditField          matlab.ui.control.NumericEditField
        startingframeEditFieldLabel     matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        SNRcalculationresultisLabel     matlab.ui.control.Label
        FigSAVEButton                   matlab.ui.control.Button
        SNRcalculationresultisTextArea  matlab.ui.control.TextArea
        PlotButton                      matlab.ui.control.Button
        FFTStartButton                  matlab.ui.control.Button
        FFTSetButton                    matlab.ui.control.Button
        SNRPixelSelectPanel             matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Y4EditField                     matlab.ui.control.NumericEditField
        Y4EditFieldLabel                matlab.ui.control.Label
        X4EditField                     matlab.ui.control.NumericEditField
        X4EditFieldLabel                matlab.ui.control.Label
        Y3EditField                     matlab.ui.control.NumericEditField
        Y3EditFieldLabel                matlab.ui.control.Label
        X3EditField                     matlab.ui.control.NumericEditField
        X3EditFieldLabel                matlab.ui.control.Label
        SNRcalculationButton            matlab.ui.control.Button
        Y2EditField                     matlab.ui.control.NumericEditField
        Y2EditFieldLabel                matlab.ui.control.Label
        X2EditField                     matlab.ui.control.NumericEditField
        X2EditFieldLabel                matlab.ui.control.Label
        Y1EditField                     matlab.ui.control.NumericEditField
        Y1EditFieldLabel                matlab.ui.control.Label
        X1EditField                     matlab.ui.control.NumericEditField
        X1EditFieldLabel                matlab.ui.control.Label
        FFTSqaureSizePanel              matlab.ui.container.Panel
        areay2_1EditField               matlab.ui.control.NumericEditField
        areay2_1EditFieldLabel          matlab.ui.control.Label
        areay1_1EditField               matlab.ui.control.NumericEditField
        areay1_1EditFieldLabel          matlab.ui.control.Label
        areax2_1EditField               matlab.ui.control.NumericEditField
        areax2_1EditFieldLabel          matlab.ui.control.Label
        areax1_1EditField               matlab.ui.control.NumericEditField
        areax1_1EditFieldLabel          matlab.ui.control.Label
        areay2_2EditField               matlab.ui.control.NumericEditField
        areay2_2EditFieldLabel          matlab.ui.control.Label
        areay1_2EditField               matlab.ui.control.NumericEditField
        areay1_2EditFieldLabel          matlab.ui.control.Label
        areax2_2EditField               matlab.ui.control.NumericEditField
        areax2_2EditFieldLabel          matlab.ui.control.Label
        areax1_2EditField               matlab.ui.control.NumericEditField
        areax1_2EditFieldLabel          matlab.ui.control.Label
        FFTPixelSelectPanel             matlab.ui.container.Panel
        numEditField                    matlab.ui.control.NumericEditField
        numEditFieldLabel               matlab.ui.control.Label
        stepEditField                   matlab.ui.control.NumericEditField
        stepEditFieldLabel              matlab.ui.control.Label
        PointYEditField                 matlab.ui.control.NumericEditField
        PointYEditFieldLabel            matlab.ui.control.Label
        PointXEditField                 matlab.ui.control.NumericEditField
        PointXEditFieldLabel            matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ReadyButton                     matlab.ui.control.Button
        MessageEditField                matlab.ui.control.EditField
        MessageEditFieldLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        % final_image_acquisition 
         
        % 카메라 검색
        adapter='gige';
        num_cams=1;
        discovery;
        cam_list;
        deviceID;

        % 카메라 연결
        format;
        vid;
        src;
        model;
        
        % 카메라 파라미터 설정
        f_L=double(0.0);
        fs=int32(50);
        frame_count=int32(0);
        heatingtime=int32(0);
        
        % 취득 변수 설정
        numFrames=int32(0);
        numTriggers=int32(0);

        % 필요 Parameter
        resolution;
        data;
        t;
        SAVE_MODE;
        
        % 함수발생기        
        visaObj;
        metadata={};     
        timex=0;
        pulsed_bias=double(0.0);
        comp=double(0.0);

        % preview
        image_acquistion_count=0;
        T0=double(0.0)
        real_time0=[];
        data_c0;
        avg_temp_fixed_Y0;
        sum_data_c0;
        area_data_c0;
        starting_frame = 259;

        % lock_in_frequency_select

        ts=double(0.0); % 샘플링 간격 [초]
        T1=double(0.0); % T1 = frame_count * ts
        df=double(0.0); % 1/T1, 주파수 해상도
        f1=[]; % 주파수 for fft plot
        real_time=[]; % 타임 벡터

        pixel_y=480;
        pixel_x=640;

        org_x=400;
        org_y=200;
        width=1500;
        height=700;
        pos;
        res;
        data_d;
        data_c;
        data_sq;

        % FFT Parameter
        % PLOT PARAMETER
        avg_temp_fixed_Y;
        temp_fft_fixed_Y;
        temp_fft_fixed_Y_DCreject;
        avg_temp_fixed_X;
        temp_fft_fixed_X;
        temp_fft_fixed_X_DCreject;
        sum_data_c_1;
        area_data_c_1;
        sum_data_c_2;
        area_data_c_2;
        temp_fft_area1;
        temp_fft_area1_DCreject;
        max_values;
        max_indices;
        temp_fft_area2;
        temp_fft_area2_DCreject;
        k_L;
        temp_fft;
        temp_fft_k_L_amp;
        temp_fft_k_L_phase;
        temp_fft_k_1_amp;
        temp_fft_k_1_phase;
        temp_fft_k_2_amp;
        temp_fft_k_2_phase;
        f_1;
        f_2;
        k_1;
        k_2;

        % (selected_point_x, selected_point_y) 에서(오른쪽 방향 or 아래쪽 방향) pixel_step 간격으로 number_point 개의
        % 각각 픽셀에서 타임-온도 신호를 출력한다. 
        selected_point_x = 310;
        selected_point_y = 240;
        pixel_step = 1;
        number_point = 8;

        % 직사각형 영역 선택 1
        area_x1_1 = 311;
        area_x2_1 = 340;

        area_y1_1 = 211;
        area_y2_1 = 240;

        % 직사각형 영역 선택 2
        area_x1_2 = 221;
        area_x2_2 = 320;

        area_y1_2 = 101;
        area_y2_2 = 300;
        
        % SNR Parameter
        SNR_x1;
        SNR_x2;
        SNR_x3;
        SNR_x4;
        SNR_y1;
        SNR_y2;
        SNR_y3;
        SNR_y4;
        All_SNR;
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: f_lockinSpinner
        function f_lockinSpinnerValueChanged(app, event)
            % f_lockin 값 변경

            value = app.f_lockinSpinner.Value;
            app.f_L=value;
            rstmsg='f_lockin value has been changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: framecountSpinner
        function framecountSpinnerValueChanged(app, event)
            % framecount 값 변경

            value = app.framecountSpinner.Value;
            app.frame_count=value;
            rstmsg='framecount value has been changed.';
            app.MessageEditField.Value=rstmsg;
        end

        % Value changed function: heatingtimeSpinner
        function heatingtimeSpinnerValueChanged2(app, event)
            % heating time 값 변경
            
            value = app.heatingtimeSpinner.Value;
            app.heatingtime=value;
            rstmsg='heatingtime value has been changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Button pushed function: SettingSaveButton
        function SetButtonPushed(app, event)
            
            app.numFrames = app.fs/(2*app.f_L); 
            % app.numTriggers = app.frame_count/app.numFrames;
            app.numTriggers = app.image_acquisition_count/app.numFrames;

            app.vid.FramesPerTrigger = app.numFrames;
            app.vid.TriggerRepeat = app.numTriggers;      
            
            app.ts = 1/double(app.fs); % 샘플링 간격 [초]
            app.T0 = app.image_acquisition_count * app.ts; %*
            app.T1 = app.frame_count * app.ts;
            
            app.df = 1/double(app.T1); % 주파수 해상도
            app.f1 = 0:app.df:double(app.fs-app.df); % 주파수 for fft plot
            
            app.real_time0 = app.ts:app.ts:app.T0; % T0 타임 벡터
            app.real_time = app.ts:app.ts:app.T1; % T1 타임 벡터
            app.pos=[app.org_x,app.org_y,app.width,app.height];
            
            app.timex=0;
            
            fprintf(app.visaObj,':SENS:CURR:PROT %f\n',app.comp); % 소스미터 최대전류값 설정
            fprintf(app.visaObj, 'SOUR:VOLT %f\n',app.pulsed_bias); % 전압 초기값 0V
            fprintf(app.visaObj, ':OUTP OFF'); % 소스미터 OUTPUT OFF

            rstmsg='Settings have been successfully saved.';
            app.MessageEditField.Value=rstmsg;

            %{
            assignin('base', 'fs', app.fs);
            assignin('base', 'numFrames', app.numFrames);
            assignin('base', 'numTriggers', app.numTriggers);
            assignin('base', 'FramsPerTrigger', app.vid.FramesPerTrigger);
            assignin('base', 'ts', app.ts);
            assignin('base', 'frame_count', app.frame_count);
            assignin('base', 'T1', app.T1);
            assignin('base', 'df', app.df);
            assignin('base', 'f1', app.f1);
            assignin('base', 'real_time', app.real_time);
            assignin('base', 'pos', app.pos);
            %}
            
        end

        % Button pushed function: ConnectButton
        function ConnectButton_IR_Pushed(app, event)
imaqreset % 카메라 리셋

app.discovery = imaqhwinfo(app.adapter);
app.deviceID = app.discovery.DeviceIDs{1};
app.format = "mono16";

% 선택한 카메라에 연결
% vid는 비디오 제어 및 획득에 사용
% src는 카메라 제어에 사용
% videoinput 클래스는 연속, 다중 프레임 획득에 사용
% imaq.VideoDevice는 단일 프레임 획득에 사용
app.vid = videoinput(app.adapter, app.deviceID);
app.src = app.vid.Source;

% 연결완료 메세지 출력
app.model = app.src.DeviceModelName;  

rstmsg='camera connected.';           
app.MessageEditField.Value=rstmsg;

pause(1);

%% 카메라 해상도 설정

% 다른 옵션은 카메라 모델에 따라 다름
% 주의: 카메라를 FLIR 소프트웨어에 연결하기 전에 반드시 방사능 모드로 전환해야 함

app.src.IRFormat = "TemperatureLinear10mK";

%% 객체 매개변수 설정

% 정확한 온도 측정에 중요한 매개변수
% 데이터가 기록된 후에는 이러한 매개변수를 변경할 수 없음

% 레플렉티브 온도 및 객체 방사율 매개변수
app.src.ReflectedTemperature = 298;
app.src.ObjectEmissivity = 1.0;

% 대기 흡수 매개변수
app.src.AtmosphericTemperature = 298;

% 트리거 방법 설정
% 수동으로 설정
triggerconfig(app.vid, 'Manual')

rstmsg='Parameter setting finished.';
app.MessageEditField.Value=rstmsg;

pause(1);

%% 함수발생기(소스미터) 연결

% VISA-Serial 객체 찾기
app.visaObj = instrfind('Type', 'visa-serial', 'RsrcName', 'ASRL3::INSTR', 'Tag', '');

% VISA-Serial 객체가 존재하지 않으면 생성
% 그렇지 않으면 찾은 객체 사용
if isempty(app.visaObj)
    app.visaObj = visa('NI', 'ASRL3::INSTR');
else
    fclose(app.visaObj);
    app.visaObj = app.visaObj(1);
end

% VISA 연결
fopen(app.visaObj);

% 연결완료 메세지 출력

rstmsg='The device has been connected.';           
app.MessageEditField.Value=rstmsg;
        end

        % Button pushed function: DisconnectButton
        function DisconnectButton_IR_Pushed(app, event)
% 카메라 연결 해제
app.src.IRFormat = "Radiometric";
imaqreset
            
%% 소스미터 연결해제
fprintf(app.visaObj, ':OUTP OFF'); % 소스미터 OUTPUT OFF
fclose(app.visaObj);

rstmsg='The device has been disconnected.';
app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: pulsedbiasSpinner
        function pulsedbiasSpinnerValueChanged(app, event)
% 펄스 바이어스 값 변경
            
value = app.pulsedbiasSpinner.Value;
app.pulsed_bias = value;
rstmsg='pulsed bias value has been changed.';
app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: complianceSpinner
        function complianceSpinnerValueChanged(app, event)
% 전류 제한값 변경
            
value = app.complianceSpinner.Value;
app.comp = value;
rstmsg='compliance value has been changed.';
app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)

        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed(app, event)
%% 이미지 취득 준비 및 메모리 사전 할당

% 이미지 취득 준비 문구 출력
rstmsg='Starting video...';           
app.MessageEditField.Value=rstmsg;

pause(1);

start(app.vid);
rstmsg='Video started.';
app.MessageEditField.Value=rstmsg;

pause(1);

% 속도를 위해 메모리를 미리 할당
app.resolution = app.vid.VideoResolution;
app.data = zeros(app.resolution(2), app.resolution(1), app.vid.NumberOfBands, app.numFrames*app.numTriggers);
app.metadata = cell(app.numFrames, app.numTriggers);
app.t = zeros(app.numFrames, app.numTriggers);

app.SNRcalculationresultisTextArea.Value = '';

rstmsg='Press start to begin image acquisition.';
app.MessageEditField.Value=rstmsg;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%% 이미지 취득 전 가열 단계

fprintf(app.visaObj, ':OUTP ON'); % 소스미터 OUTPUT ON
rstmsg='Heating in progress';
app.MessageEditField.Value=rstmsg; 

tic
for i = 1:((app.heatingtime*2)*app.f_L) % 히팅타임을 도달하기 위한 반 주기 반복횟수 : 2의 정수배를 권장 2의 정수 배일때 이 for 문이 끝나면 x=0 이 된다.
    % 각 트리거당 수행시간을 측정하기 위해 tic,toc 사용       
    % 전압을 0V 및 5V로 전환
    app.timex=~app.timex;
    disp(i);
    if app.timex == 1 
    fprintf(app.visaObj, 'SOUR:VOLT %f\n',app.pulsed_bias); % 전압을 pulsed_bias V로 설정
    elseif app.timex == 0
    fprintf(app.visaObj, 'SOUR:VOLT 0'); % 전압을 0V로 설정
    end
    pause(0.5/app.f_L); % 반 주기 ex) f_L=6.25 => 0.08
end
toc
rstmsg='Start acquisition after heating is complete';
app.MessageEditField.Value=rstmsg; 
%% 준 안정상태에서 이미지 취득 동기화

% 이미지 취득을 위한 트리거실행
for i = 1:app.numTriggers
    % 각 트리거당 수행시간을 측정하기 위해 tic,toc 사용
    tic     
    % 각 트리거 수행
    trigger(app.vid);
   
    % 메모리에서 기록된 데이터 추출
    % 이는 각 트리거 후에 수행됨
    [triggerData, triggert, triggerMetadata] = getdata(app.vid, app.numFrames, 'uint16');
    
    % 데이터, 메타데이터 및 타임스탬프 저장
    startIdx = (i - 1) * app.numFrames + 1;
    endIdx = i * app.numFrames;
    app.data(:, :, :, startIdx:endIdx) = triggerData;
    app.metadata(:, i) = {triggerMetadata};
    app.t(:, i) = triggert;
    
    % 전압을 0V 및 5V로 전환
    app.timex=~app.timex;
    disp(i);
    disp(app.timex);
    if app.timex == 1 
    fprintf(app.visaObj, 'SOUR:VOLT 0.0'); % 전압을 0V로 설정
    elseif app.timex == 0
    fprintf(app.visaObj, 'SOUR:VOLT %f\n',app.pulsed_bias); % 전압을 ~V로 설정
    end
    toc
end

% 이미지 취득 중지
stop(app.vid);
rstmsg='Acquisition finished successfully.';
app.MessageEditField.Value=rstmsg;

fprintf(app.visaObj, ':OUTP OFF'); % 소스미터 OUTPUT OFF

%{
% 소스미터 연결해제
fprintf(app.visaObj, ':OUTP OFF'); % 소스미터 OUTPUT OFF
fclose(app.visaObj);
%}
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
%% 이미지 취득 중지
stop(app.vid);

rstmsg='Image acquisition has been stopped.';
app.MessageEditField.Value=rstmsg;
        end

        % Value changed function: PointXEditField
        function PointXEditFieldValueChanged(app, event)
            value = app.PointXEditField.Value;
            app.selected_point_x = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: PointYEditField
        function PointYEditFieldValueChanged(app, event)
            value = app.PointYEditField.Value;
            app.selected_point_y = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: stepEditField
        function stepEditFieldValueChanged(app, event)
            value = app.stepEditField.Value;
            app.pixel_step = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: numEditField
        function numEditFieldValueChanged(app, event)
            value = app.numEditField.Value;
            app.number_point = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areax1_1EditField
        function areax1_1EditFieldValueChanged(app, event)
            value = app.areax1_1EditField.Value;
            app.area_x1_1 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areax2_1EditField
        function areax2_1EditFieldValueChanged(app, event)
            value = app.areax2_1EditField.Value;
            app.area_x2_1 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areay1_1EditField
        function areay1_1EditFieldValueChanged(app, event)
            value = app.areay1_1EditField.Value;
            app.area_y1_1 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areay2_1EditField
        function areay2_1EditFieldValueChanged(app, event)
            value = app.areay2_1EditField.Value;
            app.area_y2_1 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areax1_2EditField
        function areax1_2EditFieldValueChanged(app, event)
            value = app.areax1_2EditField.Value;
            app.area_x1_2 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areax2_2EditField
        function areax2_2EditFieldValueChanged(app, event)
            value = app.areax2_2EditField.Value;
            app.area_x2_2 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areay1_2EditField
        function areay1_2EditFieldValueChanged(app, event)
            value = app.areay1_2EditField.Value;
            app.area_y1_2 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: areay2_2EditField
        function areay2_2EditFieldValueChanged(app, event)
            value = app.areay2_2EditField.Value;
            app.area_y2_2 = value;
            rstmsg='The value has been successfully changed.';
            app.MessageEditField.Value=rstmsg; 
        end

        % Button pushed function: FFTSetButton
        function FFTSetButtonPushed(app, event)
%% Determine camera resolution 모드 설정
% each camera has two options for resolution
% the different options depend on camera model

% read the device model from source object

    if app.src.IRFormat == "TemperatureLinear10mK"
        app.res = 0.01;
    elseif app.src.IRFormat == "TemperatureLinear100mK"
        app.res = 0.10;
    else
        warning("Format is set to radiometric (signal linear). Data might not have been recorded as temperature linear.")
        app.res = 0.0;
    end

% f_L 과 대응되는 디지털 락인 주파수 k_L에서의 FFT
app.k_L=round(app.frame_count*(double(app.f_L)/double(app.fs)))+1;
app.temp_fft = zeros(app.pixel_y, app.pixel_x, app.frame_count);
app.temp_fft_k_L_amp = zeros(app.pixel_y, app.pixel_x);
app.temp_fft_k_L_phase = zeros(app.pixel_y, app.pixel_x);
app.temp_fft_k_1_amp = zeros(app.pixel_y, app.pixel_x);
app.temp_fft_k_1_phase = zeros(app.pixel_y, app.pixel_x);
app.temp_fft_k_2_amp = zeros(app.pixel_y, app.pixel_x);
app.temp_fft_k_2_phase = zeros(app.pixel_y, app.pixel_x);

rstmsg='FFT Settings have been successfully saved.';
app.MessageEditField.Value=rstmsg; 

        end

        % Button pushed function: FFTStartButton
        function FFTStartButtonPushed(app, event)
%% convert data 캘리브레이션

% the data from a frame is always grabbed as an integer
% convert to a double floating point number
app.data_d = double(app.data);
app.data_c = zeros(app.pixel_y, app.pixel_x, app.frame_count);
app.frame_count=512;

% for each frame, multiply all values by our resolution
% this gives value in Kelvin
% subtratc 273.15 to get temp in Celsius
% data_c는 pixel_y*pixel_x*frame_count임

for i = 1:app.frame_count %*
    app.data_c(:, :, i) = app.data_c0(:, :, app.starting_frame + i - 1);
end

%{
for f=1:size(app.data_d, 4)
    app.data_c(:, :, f) = ((app.res)*(app.data_d(:, :, 1, f)) - 273.15); %(data_k(:, :, f) = res * data_d(:, :, 1, f);)
end
%}  


%% Y축 고정 number_point개 (8개 이하 권장) 가로온도데이터 및 평균 온도데이터, 평균 온도의 fft 플롯 
colors = {'b-', 'g-', 'r-', 'c-', 'm-', 'y-','w-', 'k-'};
legend_str = cell(1, 8);
for i = 1:app.number_point
    legend_str{i} = sprintf('pixel_x=%d', app.selected_point_x + app.pixel_step*(i-1));
end

% number_point 개의 온도 데이터를 평균
app.avg_temp_fixed_Y = zeros(size(app.data_c, 3), 1);

figure('Position',app.pos);
subplot(3,1,1)
hold on;
for i = 1:app.number_point
    plot(app.real_time, squeeze(app.data_c(app.selected_point_y, app.selected_point_x + app.pixel_step*(i-1), :)), colors{i}, 'LineWidth', 0.5);
    app.avg_temp_fixed_Y = app.avg_temp_fixed_Y + squeeze(app.data_c(app.selected_point_y, app.selected_point_x + app.pixel_step*(i-1), :));
end
app.avg_temp_fixed_Y = app.avg_temp_fixed_Y / app.number_point;
hold off;
title(sprintf('Real Time Signal (fixed Y Pixel : %d)',app.selected_point_y));
xlim([0,app.frame_count*0.02]);
legend(legend_str);
xlabel('Time');
ylabel('temp');
% 그리드 추가
grid on;

%  평균 온도 데이터 플로팅
subplot(3,1,2)
plot(app.real_time, app.avg_temp_fixed_Y, 'r-o', 'LineWidth', 0.5);  
title(sprintf('%d avg temp', app.number_point));
xlabel('Time');
ylabel('temp');
xlim([0,app.frame_count*0.02]);

subplot(3,1,3)
% 평균 온도 데이터 -> fft (주파수-진폭) 플로팅
% FFT 계산
app.temp_fft_fixed_Y = fft(app.avg_temp_fixed_Y, app.frame_count) / app.frame_count;
app.temp_fft_fixed_Y_DCreject = app.temp_fft_fixed_Y(2:end/2);

% 최대값 및 인덱스 찾기
[app.max_values, app.max_indices] = maxk(abs(app.temp_fft_fixed_Y_DCreject), 10);

% 나머지 데이터 세로선에 동그라미로 표시
stem(app.f1(2:end/2), abs(app.temp_fft_fixed_Y_DCreject), 'ro', 'LineWidth', 0.5);

hold on;

% 최대값에 해당하는 상위 10개 데이터 세로선에 검은색 세모로 표시
stem(app.f1(app.max_indices+1), app.max_values, 'kv', 'MarkerFaceColor', 'k', 'LineWidth', 0.5);

% 라벨 및 타이틀 설정
xlabel('Frequency (Hz)');
ylabel('|X(fk)|');
title(sprintf('Amplitude Spectrum (Horizonal line : avg fixed Y Pixel : %d)',app.selected_point_y));
xlim([0 app.fs/4]);

hold off;

%% X축 고정 number_point개 (8개 이하 권장) 세로온도데이터 플롯
colors = {'b-', 'g-', 'r-', 'c-', 'm-', 'y-','w-', 'k-'};
legend_str = cell(1, 8);
for i = 1:app.number_point
    legend_str{i} = sprintf('pixel_y=%d', app.selected_point_y + app.pixel_step*(i-1));
end

% number_point 개의 온도 데이터를 평균
app.avg_temp_fixed_X = zeros(size(app.data_c, 3), 1);

figure('Position',app.pos);
subplot(3,1,1)
hold on;  % 다음 그래프를 겹쳐 그릴 것임
for i = 1:app.number_point
    plot(app.real_time, squeeze(app.data_c(app.selected_point_y + app.pixel_step*(i-1), app.selected_point_x, :)), colors{i}, 'LineWidth', 0.5);
    app.avg_temp_fixed_X = app.avg_temp_fixed_X + squeeze(app.data_c(app.selected_point_y + app.pixel_step*(i-1), app.selected_point_x, :));
end
app.avg_temp_fixed_X = app.avg_temp_fixed_X / app.number_point;
hold off;
title(sprintf('Real Time Signal (fixed X Pixel : %d)',app.selected_point_x));
xlim([0,app.frame_count*0.02]);
legend(legend_str);
xlabel('Time');
ylabel('temp');
% 그리드 추가
grid on;


subplot(3,1,2)
plot(app.real_time, app.avg_temp_fixed_X, 'r-o', 'LineWidth', 0.5);  %  평균 온도 데이터 플로팅
title(sprintf('%d avg temp', app.number_point));
xlabel('Time');
ylabel('temp');
xlim([0,app.frame_count*0.02]);

subplot(3,1,3)
%  평균 온도 데이터 -> fft (주파수-진폭) 플로팅
% FFT 계산
app.temp_fft_fixed_X = fft(app.avg_temp_fixed_X, app.frame_count) / app.frame_count;
app.temp_fft_fixed_X_DCreject = app.temp_fft_fixed_X(2:end/2);

% 최대값 및 인덱스 찾기
[app.max_values, app.max_indices] = maxk(abs(app.temp_fft_fixed_X_DCreject), 10);

% 나머지 데이터 세로선에 동그라미로 표시
stem(app.f1(2:end/2), abs(app.temp_fft_fixed_X_DCreject), 'ro', 'LineWidth', 0.5);

hold on;

% 최대값에 해당하는 상위 10개 데이터 세로선에 검은색 세모로 표시
stem(app.f1(app.max_indices+1), app.max_values, 'kv', 'MarkerFaceColor', 'k', 'LineWidth', 0.5);

% 라벨 및 타이틀 설정
xlabel('Frequency (Hz)');
ylabel('|X(fk)|');
title(sprintf('Amplitude Spectrum (Vertical Line : avg fixed X Pixel : %d)',app.selected_point_x));
xlim([0 app.fs/4]);

hold off;
%% 직사각형 영역에 대한 온도 평균 데이터
%1
app.sum_data_c_1 = zeros(size(app.data_c, 3), 1);
for x = app.area_x1_1:app.area_x2_1
    for y = app.area_y1_1:app.area_y2_1
        app.sum_data_c_1 = app.sum_data_c_1 + squeeze(app.data_c(y, x, :));
    end
end
app.area_data_c_1 = app.sum_data_c_1 / ((app.area_x2_1 - app.area_x1_1 + 1) * (app.area_y2_1 - app.area_y1_1 + 1));


%2
app.sum_data_c_2 = zeros(size(app.data_c, 3), 1);
for x = app.area_x1_2:app.area_x2_2
    for y = app.area_y1_2:app.area_y2_2
        app.sum_data_c_2 = app.sum_data_c_2 + squeeze(app.data_c(y, x, :));
    end
end
app.area_data_c_2 = app.sum_data_c_2 / ((app.area_x2_2 - app.area_x1_2 + 1) * (app.area_y2_2 - app.area_y1_2 + 1));

% 평균화된 그래프 플로팅
figure('Position',app.pos);
subplot(2,1,1)
plot(app.real_time, app.area_data_c_1, 'r-o', 'LineWidth', 0.5);
xlabel('Time');
ylabel('Temp'); 
title(sprintf('%d X %d area 1 avg temp', (app.area_x2_1 - app.area_x1_1 + 1), (app.area_y2_1 - app.area_y1_1 + 1)));
xlim([0,5]);

subplot(2,1,2)
%  평균 온도 데이터 -> fft (주파수-진폭) 플로팅
% FFT 계산
app.temp_fft_area1 = fft(app.area_data_c_1, app.frame_count) / app.frame_count;
app.temp_fft_area1_DCreject = app.temp_fft_area1(2:end/2);
% 최대값 및 인덱스 찾기
[app.max_values, app.max_indices] = maxk(abs(app.temp_fft_area1_DCreject), 10);
% 나머지 데이터 세로선에 동그라미로 표시
stem(app.f1(2:end/2), abs(app.temp_fft_area1_DCreject), 'ro', 'LineWidth', 0.5);
hold on;
% 최대값에 해당하는 상위 10개 데이터 세로선에 검은색 세모로 표시
stem(app.f1(app.max_indices+1), app.max_values, 'kv', 'MarkerFaceColor', 'k', 'LineWidth', 0.5);

% 라벨 및 타이틀 설정
xlabel('Frequency (Hz)');
ylabel('|X(fk)|');
title(sprintf('Amplitude Spectrum (%d X %d area)', (app.area_x2_1 - app.area_x1_1 + 1), (app.area_y2_1 - app.area_y1_1 + 1)));
xlim([0 app.fs/4]);

hold off;

figure('Position',app.pos);
subplot(2,1,1)
plot(app.real_time, app.area_data_c_2, 'r-o', 'LineWidth', 0.5);
xlabel('Time');
ylabel('Temp'); 
title(sprintf('%d X %d area2 avg temp', (app.area_x2_2 - app.area_x1_2 + 1), (app.area_y2_2 - app.area_y1_2 + 1)));
xlim([0,5]);

subplot(2,1,2)
app.temp_fft_area2 = fft(app.area_data_c_2, app.frame_count) / app.frame_count;
app.temp_fft_area2_DCreject = app.temp_fft_area2(2:end/2);
[app.max_values, app.max_indices] = maxk(abs(app.temp_fft_area2_DCreject), 10);
stem(app.f1(2:end/2), abs(app.temp_fft_area2_DCreject), 'ro', 'LineWidth', 0.5);
hold on;
stem(app.f1(app.max_indices+1), app.max_values, 'kv', 'MarkerFaceColor', 'k', 'LineWidth', 0.5);

% 라벨 및 타이틀 설정
xlabel('Frequency (Hz)');
ylabel('|X(fk)|');
title(sprintf('Amplitude Spectrum (%d X %d area)', (app.area_x2_2 - app.area_x1_2 + 1), (app.area_y2_2 - app.area_y1_2 + 1)));
xlim([0 app.fs/4]);

rstmsg='FFT processing is complete.';
app.MessageEditField.Value=rstmsg; 
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
rstmsg='Please wait...';
app.MessageEditField.Value=rstmsg; 

pause(2);

%{
%% 다른 주파수 선택
app.f_1=4.6875;
app.f_2=7.8125;
app.k_1=round(app.frame_count*(app.f_1/app.fs)+1);
app.k_2=round(app.frame_count*(app.f_2/app.fs)+1);
%}

%% Lock-in

for x = 1:app.pixel_x
    for y = 1:app.pixel_y
        app.temp_fft(y, x, :) = fft(squeeze(app.data_c(y, x, :)), app.frame_count) / app.frame_count;
        app.temp_fft_k_L_amp(y, x) = abs(app.temp_fft(y, x, app.k_L));
        app.temp_fft_k_L_phase(y, x) = angle(app.temp_fft(y, x, app.k_L));
        %{
        app.temp_fft_k_1_amp(y, x) = abs(app.temp_fft(y, x, app.k_1));
        app.temp_fft_k_1_phase(y, x) = angle(app.temp_fft(y, x, app.k_1));
        app.temp_fft_k_2_amp(y, x) = abs(app.temp_fft(y, x, app.k_2));
        app.temp_fft_k_2_phase(y, x) = angle(app.temp_fft(y, x, app.k_2));
        %}
    end
end


%% 입력에 대응되는 락인 진폭, 위상 이미지
figure('Position',app.pos);
subplot(1,2,1)
imagesc(app.temp_fft_k_L_amp);
colormap('turbo');
colorbar; 
title(sprintf('%d Hz Amplitude image', double(app.f_L)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');
pbaspect([320 240 1]); %* 그대로 바꾸기
daspect([1 1 1]); %* 그대로 바꾸기

subplot(1,2,2)
imagesc(app.temp_fft_k_L_phase);
colormap('turbo');
colorbar; 
title(sprintf('%d Hz Phase image', double(app.f_L)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');
pbaspect([320 240 1]); %* 그대로 바꾸기
daspect([1 1 1]); %* 그대로 바꾸기

%{
%% 선택한 락인f_1 진폭, 위상 이미지
figure('Position',app.pos);
subplot(1,2,1)
imagesc(app.temp_fft_k_1_amp);
colormap('hot');
colorbar; 
title(sprintf('%d Hz Amplitude image', double(app.f_1)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');

subplot(1,2,2)
imagesc(app.temp_fft_k_1_phase);
colormap('hot');
colorbar; 
title(sprintf('%d Hz Phase image', double(app.f_1)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');

%% 선택한 락인f_2 진폭, 위상 이미지
figure('Position',app.pos);
subplot(1,2,1)
imagesc(app.temp_fft_k_2_amp);
colormap('hot');
colorbar; 
title(sprintf('%d Hz Amplitude image', double(app.f_2)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');

subplot(1,2,2)
imagesc(app.temp_fft_k_2_phase);
colormap('hot');
colorbar; 
title(sprintf('%d Hz Phase image', double(app.f_2)));
xlabel('Pixel X Index');
ylabel('Pixel Y Index');

%}

rstmsg='The plotting is complete.';
app.MessageEditField.Value=rstmsg; 
        end

        % Button pushed function: SNRcalculationButton
        function SNRcalculationButtonPushed(app, event)
%% SNR
% 전체 영역 설정
app.SNR_x1 = 276;
app.SNR_y1 = 358;
app.SNR_x2 = 305;
app.SNR_y2 = 387;

%% 전체 영역 내의 결함 영역 설정
app.SNR_x3 = 286;
app.SNR_y3 = 368;
app.SNR_x4 = 295;
app.SNR_y4 = 377;

%% amp, phase 영역 선택
app.area_k_L_amp = app.temp_fft_k_L_amp(app.SNR_y1:app.SNR_y2, app.SNR_x1:app.SNR_x2);
app.area_k_L_phase = app.temp_fft_k_L_phase(app.SNR_y1:app.SNR_y2, app.SNR_x1:app.SNR_x2);

%% temp_fft_k_L_amp 진폭이미지 SNR 
% 결함 영역 설정
defect_region_k_L_amp = false(size(area_k_L_amp1)); % 전체 영역을 결함이 없는 것으로 초기화
defect_region_k_L_amp(app.SNR_y3-app.SNR_y1+1:app.SNR_y4-app.SNR_y1+1, app.SNR_x3-app.SNR_x1+1:app.SNR_x4-app.SNR_x1+1) = true; % 결함 영역 설정

% 비결함 영역 설정
nondefect_region_k_L_amp = ~defect_region_k_L_amp;

% 결함 영역과 비결함 영역에서의 평균 및 표준 편차 계산
mean_defect_k_L_amp = mean(area_k_L_amp1(defect_region_k_L_amp), 'all');
mean_nondefect_k_L_amp = mean(area_k_L_amp1(nondefect_region_k_L_amp), 'all');
std_nondefect_k_L_amp = std(area_k_L_amp1(nondefect_region_k_L_amp), 0, 'all');

% SNR 계산
SNR_k_L_amp = 20 * log10(abs(mean_defect_k_L_amp - mean_nondefect_k_L_amp) / std_nondefect_k_L_amp);
SNR_amp = ['SNR_k_L_amp1: ', num2str(SNR_k_L_amp), ' dB'];

%% temp_fft_k_L_phase 위상이미지 SNR
% 결함 영역 설정
defect_region_k_L_phase = false(size(area_k_L_phase1)); % 전체 영역을 결함이 없는 것으로 초기화
defect_region_k_L_phase(app.SNR_y3-app.SNR_y1+1:app.SNR_y4-app.SNR_y1+1, app.SNR_x3-app.SNR_x1+1:app.SNR_x4-app.SNR_x1+1) = true; % 결함 영역 설정

% 비결함 영역 설정
nondefect_region_k_L_phase = ~defect_region_k_L_phase;

% 결함 영역과 비결함 영역에서의 평균 및 표준 편차 계산
mean_defect_k_L_phase = mean(area_k_L_phase1(defect_region_k_L_phase), 'all');
mean_nondefect_k_L_phase = mean(area_k_L_phase1(nondefect_region_k_L_phase), 'all');
std_nondefect_k_L_phase = std(area_k_L_phase1(nondefect_region_k_L_phase), 0, 'all');

% SNR 계산
SNR_k_L_phase = 20 * log10(abs(mean_defect_k_L_phase - mean_nondefect_k_L_phase) / std_nondefect_k_L_phase);
SNR_phase = ['SNR_k_L_phase1 : ', num2str(SNR_k_L_phase), ' dB'];

app.All_SNR = [SNR_amp, newline, SNR_phase];

app.SNRcalculationresultisTextArea.Value = app.All_SNR;
rstmsg='SNR calculation is complete.';
app.MessageEditField.Value=rstmsg; 
        end

        % Button pushed function: debugButton
        function debugButtonPushed(app, event)
            assignin('base', 'src', app.src);
            assignin('base', 'vid', app.vid);
            assignin('base', 'data', app.data);
            assignin('base', 'data_c', app.data_c);
            assignin('base', 'data_d', app.data_d);
            assignin('base', 'real_time', app.real_time);
            assignin('base','k_L',app.k_L);
            assignin('base','f_L',app.f_L)
            assignin('base', 'temp_fft', app.temp_fft);
            assignin('base', 'temp_fft_k_L_amp', app.temp_fft_k_L_amp);
            assignin('base', 'temp_fft_k_L_phase', app.temp_fft_k_L_phase);
            assignin('base', 'pixel_x', app.pixel_x);
            assignin('base', 'pixel_y', app.pixel_y);
            assignin('base', 'temp_fft_fixed_X', app.temp_fft_fixed_X);
            assignin('base', 'temp_fft_fixed_Y', app.temp_fft_fixed_Y);
        end

        % Button pushed function: FigSAVEButton
        function FigSAVEButtonPushed(app, event)
        
        %모든 그림 창을 반복하여 저장
        fig_handles = findall(0, 'type', 'figure'); % 현재 열려 있는 모든 그림 창 찾기
        for i = 1:length(fig_handles)
            fig = fig_handles(i);
            filename = sprintf('image_%d.png', i); % 이미지 파일 이름 생성
            saveas(fig, filename); % 그림 창을 이미지로 저장
        end

        rstmsg='Plot Image Saved.';
        app.MessageEditField.Value=rstmsg; 
        end

        % Value changed function: X1EditField
        function X1EditFieldValueChanged(app, event)
            value = app.X1EditField.Value;
            app.SNR_x1=value;
        end

        % Value changed function: X2EditField
        function X2EditFieldValueChanged(app, event)
            value = app.X2EditField.Value;
            app.SNR_x2=value;
        end

        % Value changed function: Y1EditField
        function Y1EditFieldValueChanged(app, event)
            value = app.Y1EditField.Value;
            app.SNR_y1=value;
        end

        % Value changed function: Y2EditField
        function Y2EditFieldValueChanged(app, event)
            value = app.Y2EditField.Value;
            app.SNR_y2=value;
        end

        % Value changed function: SNRcalculationresultisTextArea
        function SNRcalculationresultisTextAreaValueChanged(app, event)
        
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
app.data_c0 = zeros(app.pixel_y, app.pixel_x, image_acquisition_count);
       
for f=1:size(app.data_d, 4)
   app.data_c0(:, :, f) = ((app.res)*(app.data_d(:, :, 1, f)) - 273.15); %(data_k(:, :, f) = res * data_d(:, :, 1, f);)
end

%% Y축 고정 number_point개 (8개 이하 권장) 가로온도데이터, number_point개 평균 온도데이터, 영역에 대한 온도 데이터 : 신규! (기능은 동일합니다 원래 변수들에서 0혹은 _0을 붙여 사용합니다. 온도를 Prewiew하는 기능에 해당합니다.)
colors = {'b-', 'g-', 'r-', 'c-', 'm-', 'y-','w-', 'k-'};
legend_str = cell(1, 8);
for i = 1:app.number_point
    legend_str{i} = sprintf('pixel_x=%d', app.selected_point_x + app.pixel_step*(i-1));
end

% number_point 개의 온도 데이터를 평균
app.avg_temp_fixed_Y0 = zeros(size(app.data_c0, 3), 1);

figure('Position',app.pos);
subplot(3,1,1)
hold on;
for i = 1:app.number_point
    plot(app.real_time0, squeeze(app.data_c0(app.selected_point_y, app.selected_point_x + app.pixel_step*(i-1), :)), colors{i}, 'LineWidth', 0.5); %*
    app.avg_temp_fixed_Y0 = app.avg_temp_fixed_Y0 + squeeze(app.data_c0(app.selected_point_y, app.selected_point_x + app.pixel_step*(i-1), :)); %*
end

app.avg_temp_fixed_Y0 = app.avg_temp_fixed_Y0 / app.number_point; %*
hold off;
title(sprintf('Real Time Signal (fixed Y Pixel : %d)',app.selected_point_y));
xlim([0,image_acquisition_count*0.02]); %*
legend(legend_str);
xlabel('Time');
ylabel('temp');
% 그리드 추가
grid on;

%  평균 온도 데이터 플로팅
subplot(3,1,2)
plot(app.real_time0, app.avg_temp_fixed_Y0, 'r-o', 'LineWidth', 0.5);   %*
title(sprintf('%d avg temp', app.number_point));
xlabel('Time');
ylabel('temp');
xlim([0,image_acquisition_count*0.02]); %*

subplot(3,1,3)
app.sum_data_c0 = zeros(size(app.data_c0, 3), 1); %*
for x = app.area_x1_1:app.area_x2_1
    for y = app.area_y1_1:app.area_y2_1
        app.sum_data_c0 = app.sum_data_c0 + squeeze(app.data_c0(y, x, :)); %*
    end
end

app.area_data_c0 = app.sum_data_c0 / ((app.area_x2_1 - app.area_x1_1 + 1) * (app.area_y2_1 - app.area_y1_1 + 1)); %*
plot(app.real_time0, app.area_data_c0, 'r-o', 'LineWidth', 0.5); %*
xlabel('Time');
ylabel('Temp'); 
title(sprintf('%d X %d area 1 avg temp', (app.area_x2_1 - app.area_x1_1 + 1), (app.area_y2_1 - app.area_y1_1 + 1)));

        end

        % Value changed function: previewnumSpinner
        function previewnumSpinnerValueChanged(app, event)
            value = app.previewnumSpinner.Value;
            app.image_acquistion_count=value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {503, 503};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {227, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.f_lockinSpinnerLabel = uilabel(app.LeftPanel);
            app.f_lockinSpinnerLabel.Text = 'f_lockin';
            app.f_lockinSpinner = uispinner(app.LeftPanel);
            app.f_lockinSpinner.ValueDisplayFormat = '%11.4gHz';
            app.f_lockinSpinner.ValueChangedFcn = createCallbackFcn(app, @f_lockinSpinnerValueChanged, true);
            app.f_lockinSpinner.Value = 6.25;
            app.framecountSpinnerLabel = uilabel(app.LeftPanel);
            app.framecountSpinnerLabel.Text = 'frame count';
            app.framecountSpinner = uispinner(app.LeftPanel);
            app.framecountSpinner.ValueDisplayFormat = '%d';
            app.framecountSpinner.ValueChangedFcn = createCallbackFcn(app, @framecountSpinnerValueChanged, true);
            app.framecountSpinner.Value = 50;
            app.imageprocessingparameterLabel = uilabel(app.LeftPanel);
            app.imageprocessingparameterLabel.Text = 'image processing parameter';
            app.LockinThermographyusingFFTLabel = uilabel(app.LeftPanel);
            app.LockinThermographyusingFFTLabel.Text = 'Lock-in Thermography using FFT';
            app.SettingSaveButton = uibutton(app.LeftPanel, 'push');
            app.SettingSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SettingSaveButton.Text = 'Setting Save';
            app.heatingtimeSpinnerLabel = uilabel(app.LeftPanel);
            app.heatingtimeSpinnerLabel.Text = 'heating time';
            app.heatingtimeSpinner = uispinner(app.LeftPanel);
            app.heatingtimeSpinner.ValueDisplayFormat = '%ds';
            app.heatingtimeSpinner.ValueChangedFcn = createCallbackFcn(app, @heatingtimeSpinnerValueChanged2, true);
            app.heatingtimeSpinner.Value = 5;
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_IR_Pushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.pulsedbiasSpinnerLabel = uilabel(app.LeftPanel);
            app.pulsedbiasSpinnerLabel.Text = 'pulsed bias';
            app.pulsedbiasSpinner = uispinner(app.LeftPanel);
            app.pulsedbiasSpinner.Step = 0.5;
            app.pulsedbiasSpinner.Limits = [-10 10];
            app.pulsedbiasSpinner.ValueDisplayFormat = '%11.4gV';
            app.pulsedbiasSpinner.ValueChangedFcn = createCallbackFcn(app, @pulsedbiasSpinnerValueChanged, true);
            app.pulsedbiasSpinner.Value = 10;
            app.pulsedbiasparamterSCPILabel = uilabel(app.LeftPanel);
            app.pulsedbiasparamterSCPILabel.Text = 'pulsed bias paramter(SCPI)';
            app.complianceSpinnerLabel = uilabel(app.LeftPanel);
            app.complianceSpinnerLabel.Text = 'compliance';
            app.complianceSpinner = uispinner(app.LeftPanel);
            app.complianceSpinner.Step = 0.1;
            app.complianceSpinner.Limits = [0 1];
            app.complianceSpinner.ValueDisplayFormat = '%11.4gA';
            app.complianceSpinner.ValueChangedFcn = createCallbackFcn(app, @complianceSpinnerValueChanged, true);
            app.complianceSpinner.Value = 1;
            app.EnpLabTextAreaLabel = uilabel(app.LeftPanel);
            app.EnpLabTextAreaLabel.Text = 'Enp Lab';
            app.EnpLabTextArea = uitextarea(app.LeftPanel);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '엠블럼2.png');
            app.debugButton = uibutton(app.LeftPanel, 'push');
            app.debugButton.ButtonPushedFcn = createCallbackFcn(app, @debugButtonPushed, true);
            app.debugButton.Text = 'debug';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_IR_Pushed, true);
            app.ConnectButton.Text = 'Connect';
            app.previewnumSpinnerLabel = uilabel(app.LeftPanel);
            app.previewnumSpinnerLabel.Text = 'preview num';
            app.previewnumSpinner = uispinner(app.LeftPanel);
            app.previewnumSpinner.ValueDisplayFormat = '%d';
            app.previewnumSpinner.ValueChangedFcn = createCallbackFcn(app, @previewnumSpinnerValueChanged, true);
            app.previewnumSpinner.Value = 50;
            app.RightPanel = uipanel(app.GridLayout);
            app.MessageEditFieldLabel = uilabel(app.RightPanel);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.RightPanel, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.ReadyButton = uibutton(app.RightPanel, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed, true);
            app.ReadyButton.Text = 'Ready';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.FFTPixelSelectPanel = uipanel(app.RightPanel);
            app.FFTPixelSelectPanel.Title = 'FFT Pixel Select';
            app.PointXEditFieldLabel = uilabel(app.FFTPixelSelectPanel);
            app.PointXEditFieldLabel.Text = 'Point X';
            app.PointXEditField = uieditfield(app.FFTPixelSelectPanel, 'numeric');
            app.PointXEditField.ValueChangedFcn = createCallbackFcn(app, @PointXEditFieldValueChanged, true);
            app.PointYEditFieldLabel = uilabel(app.FFTPixelSelectPanel);
            app.PointYEditFieldLabel.Text = 'Point Y';
            app.PointYEditField = uieditfield(app.FFTPixelSelectPanel, 'numeric');
            app.PointYEditField.ValueChangedFcn = createCallbackFcn(app, @PointYEditFieldValueChanged, true);
            app.stepEditFieldLabel = uilabel(app.FFTPixelSelectPanel);
            app.stepEditFieldLabel.Text = 'step';
            app.stepEditField = uieditfield(app.FFTPixelSelectPanel, 'numeric');
            app.stepEditField.ValueChangedFcn = createCallbackFcn(app, @stepEditFieldValueChanged, true);
            app.numEditFieldLabel = uilabel(app.FFTPixelSelectPanel);
            app.numEditFieldLabel.Text = 'num';
            app.numEditField = uieditfield(app.FFTPixelSelectPanel, 'numeric');
            app.numEditField.ValueChangedFcn = createCallbackFcn(app, @numEditFieldValueChanged, true);
            app.FFTSqaureSizePanel = uipanel(app.RightPanel);
            app.FFTSqaureSizePanel.Title = 'FFT Sqaure Size';
            app.areax1_2EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areax1_2EditFieldLabel.Text = 'area x1_2';
            app.areax1_2EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areax1_2EditField.ValueChangedFcn = createCallbackFcn(app, @areax1_2EditFieldValueChanged, true);
            app.areax2_2EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areax2_2EditFieldLabel.Text = 'area x2_2';
            app.areax2_2EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areax2_2EditField.ValueChangedFcn = createCallbackFcn(app, @areax2_2EditFieldValueChanged, true);
            app.areay1_2EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areay1_2EditFieldLabel.Text = 'area y1_2';
            app.areay1_2EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areay1_2EditField.ValueChangedFcn = createCallbackFcn(app, @areay1_2EditFieldValueChanged, true);
            app.areay2_2EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areay2_2EditFieldLabel.Text = 'area y2_2';
            app.areay2_2EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areay2_2EditField.ValueChangedFcn = createCallbackFcn(app, @areay2_2EditFieldValueChanged, true);
            app.areax1_1EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areax1_1EditFieldLabel.Text = 'area x1_1';
            app.areax1_1EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areax1_1EditField.ValueChangedFcn = createCallbackFcn(app, @areax1_1EditFieldValueChanged, true);
            app.areax2_1EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areax2_1EditFieldLabel.Text = 'area x2_1';
            app.areax2_1EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areax2_1EditField.ValueChangedFcn = createCallbackFcn(app, @areax2_1EditFieldValueChanged, true);
            app.areay1_1EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areay1_1EditFieldLabel.Text = 'area y1_1';
            app.areay1_1EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areay1_1EditField.ValueChangedFcn = createCallbackFcn(app, @areay1_1EditFieldValueChanged, true);
            app.areay2_1EditFieldLabel = uilabel(app.FFTSqaureSizePanel);
            app.areay2_1EditFieldLabel.Text = 'area y2_1';
            app.areay2_1EditField = uieditfield(app.FFTSqaureSizePanel, 'numeric');
            app.areay2_1EditField.ValueChangedFcn = createCallbackFcn(app, @areay2_1EditFieldValueChanged, true);
            app.SNRPixelSelectPanel = uipanel(app.RightPanel);
            app.SNRPixelSelectPanel.Title = 'SNR Pixel Select';
            app.X1EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.X1EditField.ValueChangedFcn = createCallbackFcn(app, @X1EditFieldValueChanged, true);
            app.Y1EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.Y1EditField.ValueChangedFcn = createCallbackFcn(app, @Y1EditFieldValueChanged, true);
            app.X2EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.X2EditField.ValueChangedFcn = createCallbackFcn(app, @X2EditFieldValueChanged, true);
            app.Y2EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.Y2EditField.ValueChangedFcn = createCallbackFcn(app, @Y2EditFieldValueChanged, true);
            app.SNRcalculationButton = uibutton(app.SNRPixelSelectPanel, 'push');
            app.SNRcalculationButton.ButtonPushedFcn = createCallbackFcn(app, @SNRcalculationButtonPushed, true);
            app.SNRcalculationButton.Text = 'SNR calculation';
            app.X3EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.X3EditFieldLabel.Text = 'X3';
            app.X3EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.Y3EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.Y3EditFieldLabel.Text = 'Y3';
            app.Y3EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.X4EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.X4EditFieldLabel.Text = 'X4';
            app.X4EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.Y4EditFieldLabel = uilabel(app.SNRPixelSelectPanel);
            app.Y4EditFieldLabel.Text = 'Y4';
            app.Y4EditField = uieditfield(app.SNRPixelSelectPanel, 'numeric');
            app.Label = uilabel(app.SNRPixelSelectPanel);
            app.Label.Text = '전체 영역 설정';
            app.Label_2 = uilabel(app.SNRPixelSelectPanel);
            app.Label_2.Text = '전체 영역 내의 결함 영역 설정';
            app.FFTSNRPanel = uipanel(app.RightPanel);
            app.FFTSNRPanel.Title = 'FFT & SNR';
            app.FFTSetButton = uibutton(app.FFTSNRPanel, 'push');
            app.FFTSetButton.ButtonPushedFcn = createCallbackFcn(app, @FFTSetButtonPushed, true);
            app.FFTSetButton.Text = 'FFT Set';
            app.FFTStartButton = uibutton(app.FFTSNRPanel, 'push');
            app.FFTStartButton.ButtonPushedFcn = createCallbackFcn(app, @FFTStartButtonPushed, true);
            app.FFTStartButton.Text = 'FFT Start';
            app.PlotButton = uibutton(app.FFTSNRPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SNRcalculationresultisTextArea = uitextarea(app.FFTSNRPanel);
            app.SNRcalculationresultisTextArea.ValueChangedFcn = createCallbackFcn(app, @SNRcalculationresultisTextAreaValueChanged, true);
            app.FigSAVEButton = uibutton(app.FFTSNRPanel, 'push');
            app.FigSAVEButton.ButtonPushedFcn = createCallbackFcn(app, @FigSAVEButtonPushed, true);
            app.FigSAVEButton.Text = 'Fig SAVE';
            app.SNRcalculationresultisLabel = uilabel(app.FFTSNRPanel);
            app.SNRcalculationresultisLabel.Text = 'SNR calculation result is:';
            app.PreviewButton = uibutton(app.FFTSNRPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.startingframeEditFieldLabel = uilabel(app.FFTSNRPanel);
            app.startingframeEditFieldLabel.Text = 'starting frame';
            app.startingframeEditField = uieditfield(app.FFTSNRPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIT_FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end